classdef App_Forward_Kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RzSpinner               matlab.ui.control.Spinner
        RzSpinnerLabel          matlab.ui.control.Label
        RySpinner               matlab.ui.control.Spinner
        RySpinnerLabel          matlab.ui.control.Label
        RxSpinner               matlab.ui.control.Spinner
        RxSpinnerLabel          matlab.ui.control.Label
        InverseKinematicsLabel  matlab.ui.control.Label
        ForwardKinematicsLabel  matlab.ui.control.Label
        zSpinner                matlab.ui.control.Spinner
        zSpinnerLabel           matlab.ui.control.Label
        ySpinner                matlab.ui.control.Spinner
        ySpinnerLabel           matlab.ui.control.Label
        xSpinner                matlab.ui.control.Spinner
        xSpinnerLabel           matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        Theta6Spinner           matlab.ui.control.Spinner
        Theta6SpinnerLabel      matlab.ui.control.Label
        Theta5Spinner           matlab.ui.control.Spinner
        Theta5SpinnerLabel      matlab.ui.control.Label
        Theta4Spinner           matlab.ui.control.Spinner
        Theta4SpinnerLabel      matlab.ui.control.Label
        Theta3Spinner           matlab.ui.control.Spinner
        Theta3SpinnerLabel      matlab.ui.control.Label
        Theta2Spinner           matlab.ui.control.Spinner
        Theta2SpinnerLabel      matlab.ui.control.Label
        Theta1Spinner           matlab.ui.control.Spinner
        Theta1SpinnerLabel      matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ax;
        world;
        T_0_EE;
        th;
    end
    
    methods (Access = private)
        
        function updateVR(app)
            joints = ["Joint1_Transform","Joint2_Transform",...
                      "Joint3_Transform","Joint4_Transform",...
                      "Joint5_Transform","Joint6_Transform"];
            app.world = setfield(app.world,joints(1),'rotation',[0 1 0 app.th(1)]);
            app.world = setfield(app.world,joints(2),'rotation',[0 1 0 -app.th(2)]);
            app.world = setfield(app.world,joints(3),'rotation',[0 1 0 app.th(3)]);
            app.world = setfield(app.world,joints(4),'rotation',[0 1 0 -app.th(4)]);
            app.world = setfield(app.world,joints(5),'rotation',[0 1 0 -app.th(5)]);
            app.world = setfield(app.world,joints(6),'rotation',[0 1 0 -app.th(6)]);
        end
        
        function updateTheta(app)
            app.th = deg2rad([get(app.Theta1Spinner,'Value');
                              get(app.Theta2Spinner,'Value');
                              get(app.Theta3Spinner,'Value');
                              get(app.Theta4Spinner,'Value');
                              get(app.Theta5Spinner,'Value');
                              get(app.Theta6Spinner,'Value')]');
        end
        
        function updateikSpinners(app)
            set(app.xSpinner, 'Value', round(app.T_0_EE(1,4),3));
            set(app.ySpinner, 'Value', round(app.T_0_EE(2,4),3));
            set(app.zSpinner, 'Value', round(app.T_0_EE(3,4),3));

        end
        
        function updatefkSpinners(app)
            set(app.Theta1Spinner, 'Value', round(rad2deg(app.th(1)),2));
            set(app.Theta2Spinner, 'Value', round(rad2deg(app.th(2)),2));
            set(app.Theta3Spinner, 'Value', round(rad2deg(app.th(3)),2));
            set(app.Theta4Spinner, 'Value', round(rad2deg(app.th(4)),2));
            set(app.Theta5Spinner, 'Value', round(rad2deg(app.th(5)),2));
            set(app.Theta6Spinner, 'Value', round(rad2deg(app.th(6)),2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateTheta(app);

            app.ax = app.UIAxes;
            app.th = zeros(1,6);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            showRobot(app.ax, T);
            
            app.world = vrworld('Robot_World.WRL');
            open(app.world);
            myCanvas = vr.canvas(app.world);
            updateVR(app);
            movegui('east'); movegui(app.UIFigure,[100 100]);
            set(app.UITable, 'Data', app.T_0_EE);

            updateikSpinners(app);
        end

        % Value changing function: Theta1Spinner
        function Theta1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th(1) = 2*pi/360*changingValue;
            updateVR(app);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_EE);
            showRobot(app.ax, T);
            updateikSpinners(app);
        end

        % Value changing function: Theta2Spinner
        function Theta2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th(2) = 2*pi/360*changingValue;
            updateVR(app);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_EE);
            showRobot(app.ax, T);
            updateikSpinners(app);
        end

        % Value changing function: Theta3Spinner
        function Theta3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th(3) = 2*pi/360*changingValue;
            updateVR(app);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_EE);
            showRobot(app.ax, T);
            updateikSpinners(app);
        end

        % Value changing function: Theta4Spinner
        function Theta4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th(4) = 2*pi/360*changingValue;
            updateVR(app);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_EE);
            showRobot(app.ax, T);
            updateikSpinners(app);
        end

        % Value changing function: Theta5Spinner
        function Theta5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th(5) = 2*pi/360*changingValue;
            updateVR(app);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_EE);
            showRobot(app.ax, T);
            updateikSpinners(app);
        end

        % Value changing function: Theta6Spinner
        function Theta6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.th(6) = 2*pi/360*changingValue;
            updateVR(app);
            [app.T_0_EE, T] = forwardKinematics(app.th);
            set(app.UITable, 'Data', app.T_0_EE);
            showRobot(app.ax, T);
            updateikSpinners(app);
        end

        % Value changing function: xSpinner
        function xSpinnerValueChanging(app, event)
            changingValue = event.Value;

            app.T_0_EE(1,4) = changingValue;
            set(app.UITable, 'Data', app.T_0_EE);
            IK = robotIK(app.T_0_EE);
            updateTheta(app);
            app.th(2) = app.th(2) - pi/2;
            NP = abs(IK - app.th);
            s = sum(NP,2);

            app.th = IK(sum(NP,2) == min(s),:);
            app.th(2) = app.th(2) + pi/2;

            [app.T_0_EE, T] = forwardKinematics(app.th);
            showRobot(app.ax, T);
            updatefkSpinners(app);
            updateVR(app);
        end

        % Value changing function: ySpinner
        function ySpinnerValueChanging(app, event)
            changingValue = event.Value;

            app.T_0_EE(2,4) = changingValue;
            set(app.UITable, 'Data', app.T_0_EE);
            IK = robotIK(app.T_0_EE);

            updateTheta(app);
            app.th(2) = app.th(2) - pi/2;

            NP = abs(IK - app.th);
            s = sum(NP,2);
            i = s == min(s);
            app.th = IK(i,:);
            app.th(2) = app.th(2) + pi/2;

            [app.T_0_EE, T] = forwardKinematics(app.th);
            showRobot(app.ax, T);
            updatefkSpinners(app);
            updateVR(app);
        end

        % Value changing function: zSpinner
        function zSpinnerValueChanging(app, event)
            changingValue = event.Value;

            app.T_0_EE(3,4) = changingValue;
            set(app.UITable, 'Data', app.T_0_EE);
            IK = robotIK(app.T_0_EE);

            updateTheta(app);
            app.th(2) = app.th(2) - pi/2;

            NP = abs(IK - app.th);
            s = sum(NP,2);
            i = s == min(s);
            app.th = IK(i,:);

            app.th(2) = app.th(2) + pi/2;

            [app.T_0_EE, T] = forwardKinematics(app.th);
            showRobot(app.ax, T);
            updatefkSpinners(app);
            updateVR(app);
        end

        % Value changing function: RxSpinner
        function RxSpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue = deg2rad(changingValue);
            
            T = eul2rotm([0 changingValue 0])

            app.T_0_EE(1:3,1:3) =  app.T_0_EE(1:3,1:3)*T;


            set(app.UITable, 'Data', app.T_0_EE);
            IK = robotIK(app.T_0_EE);

            app.th(2) = app.th(2) - pi/2;

            NP = abs(IK - app.th);
            s = sum(NP,2);
            i = s == min(s);
            app.th = IK(i,:);
            app.th(2) = app.th(2) + pi/2;

            [app.T_0_EE, T] = forwardKinematics(app.th);
            showRobot(app.ax, T);
            updatefkSpinners(app);
            updateVR(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Theta1SpinnerLabel = uilabel(app.UIFigure);
            app.Theta1SpinnerLabel.Text = 'Theta 1';
            app.Theta1Spinner = uispinner(app.UIFigure);
            app.Theta1Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta1SpinnerValueChanging, true);
            app.Theta1Spinner.Limits = [-360 360];
            app.Theta2SpinnerLabel = uilabel(app.UIFigure);
            app.Theta2SpinnerLabel.Text = 'Theta 2';
            app.Theta2Spinner = uispinner(app.UIFigure);
            app.Theta2Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta2SpinnerValueChanging, true);
            app.Theta2Spinner.Limits = [-360 360];
            app.Theta3SpinnerLabel = uilabel(app.UIFigure);
            app.Theta3SpinnerLabel.Text = 'Theta 3';
            app.Theta3Spinner = uispinner(app.UIFigure);
            app.Theta3Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta3SpinnerValueChanging, true);
            app.Theta3Spinner.Limits = [-360 360];
            app.Theta4SpinnerLabel = uilabel(app.UIFigure);
            app.Theta4SpinnerLabel.Text = 'Theta 4';
            app.Theta4Spinner = uispinner(app.UIFigure);
            app.Theta4Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta4SpinnerValueChanging, true);
            app.Theta4Spinner.Limits = [-360 360];
            app.Theta5SpinnerLabel = uilabel(app.UIFigure);
            app.Theta5SpinnerLabel.Text = 'Theta 5';
            app.Theta5Spinner = uispinner(app.UIFigure);
            app.Theta5Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta5SpinnerValueChanging, true);
            app.Theta5Spinner.Limits = [-360 360];
            app.Theta6SpinnerLabel = uilabel(app.UIFigure);
            app.Theta6SpinnerLabel.Text = 'Theta 6';
            app.Theta6Spinner = uispinner(app.UIFigure);
            app.Theta6Spinner.ValueChangingFcn = createCallbackFcn(app, @Theta6SpinnerValueChanging, true);
            app.Theta6Spinner.Limits = [-360 360];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.xSpinnerLabel = uilabel(app.UIFigure);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.UIFigure);
            app.xSpinner.Step = 0.001;
            app.xSpinner.ValueChangingFcn = createCallbackFcn(app, @xSpinnerValueChanging, true);
            app.ySpinnerLabel = uilabel(app.UIFigure);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.UIFigure);
            app.ySpinner.Step = 0.001;
            app.ySpinner.ValueChangingFcn = createCallbackFcn(app, @ySpinnerValueChanging, true);
            app.zSpinnerLabel = uilabel(app.UIFigure);
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.UIFigure);
            app.zSpinner.Step = 0.001;
            app.zSpinner.ValueChangingFcn = createCallbackFcn(app, @zSpinnerValueChanging, true);
            app.ForwardKinematicsLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.RxSpinnerLabel = uilabel(app.UIFigure);
            app.RxSpinnerLabel.Text = 'Rx';
            app.RxSpinner = uispinner(app.UIFigure);
            app.RxSpinner.Step = 0.1;
            app.RxSpinner.ValueChangingFcn = createCallbackFcn(app, @RxSpinnerValueChanging, true);
            app.RySpinnerLabel = uilabel(app.UIFigure);
            app.RySpinnerLabel.Text = 'Ry';
            app.RySpinner = uispinner(app.UIFigure);
            app.RySpinner.Step = 0.1;
            app.RzSpinnerLabel = uilabel(app.UIFigure);
            app.RzSpinnerLabel.Text = 'Rz';
            app.RzSpinner = uispinner(app.UIFigure);
            app.RzSpinner.Step = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Forward_Kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end